classdef Recorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        WindowSizeSlider           matlab.ui.control.Slider
        WindowSizeLabel            matlab.ui.control.Label
        movingaveragefilterButton  matlab.ui.control.Button
        AddnoiseButton             matlab.ui.control.Button
        FrequencyanalysisButton    matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        RecordButton               matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        recData
        noiseData = false
        isNoise
        disnoisedData
        Fs = 44100
        nBits = 16
        nChannels = 1
        recordingDuration = 5
    end
    
    methods (Access = private)
        
        function results = displayT(app, Data)
            plot(app.UIAxes, Data)
            return
        end
        
        function results = displayF(app, Data)
            L = length(Data);
            f = (-L/2:L/2-1)*(app.Fs/L);
            freData = fftshift(fft(Data));
            plot(app.UIAxes_2, f, abs(freData));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            recorder = audiorecorder(app.Fs, app.nBits, app.nChannels);

            disp('Start...')
            recordblocking(recorder, app.recordingDuration)
            disp('Finish.')

            app.recData = getaudiodata(recorder);
            app.displayT(app.recData);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.recData)
                disp('Empty Error!')
                return
            end

            if app.isNoise
                sound(app.noiseData, app.Fs, app.nBits);
            else
                sound(app.recData, app.Fs, app.nBits);
            end
        end

        % Button pushed function: FrequencyanalysisButton
        function FrequencyanalysisButtonPushed(app, event)
            app.displayF(app.recData);
        end

        % Button pushed function: AddnoiseButton
        function AddnoiseButtonPushed(app, event)
            L = length(app.recData);
            SD = 0.1;
            app.noiseData = SD * randn(L, 1);
            plot(app.UIAxes, app.noiseData)

            app.displayF(app.noiseData)
            
            app.recData = app.recData + app.noiseData;
            app.isNoise = true;
        end

        % Button pushed function: movingaveragefilterButton
        function movingaveragefilterButtonPushed(app, event)
            L = length(app.recData);

            if app.WindowSizeSlider.Value == 0
                WindowSize = round(L/10000);
            else
                WindowSize = round(L/(5000+10000*app.WindowSizeSlider.Value/100));
            end
            
            WindowFunc(1:L) = 0;
            WindowFunc(1:WindowSize) = 1/WindowSize;
            app.disnoisedData = conv(app.recData, WindowFunc);

            app.displayT(app.disnoisedData)
            app.displayF(app.disnoisedData)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'speech display')
            app.UIAxes.YLim = [-1.5 1.5];
            app.UIAxes.YTick = [-1.5 -1 -0.5 0 0.5 1 1.5];
            app.UIAxes.YTickLabel = {'-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'spectrum')
            xlabel(app.UIAxes_2, {''; 'Frequency(Hz)'})
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FrequencyanalysisButton = uibutton(app.UIFigure, 'push');
            app.FrequencyanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyanalysisButtonPushed, true);
            app.FrequencyanalysisButton.Text = {'Frequency-'; 'analysis'};
            app.AddnoiseButton = uibutton(app.UIFigure, 'push');
            app.AddnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddnoiseButtonPushed, true);
            app.AddnoiseButton.Text = 'Add-noise';
            app.movingaveragefilterButton = uibutton(app.UIFigure, 'push');
            app.movingaveragefilterButton.ButtonPushedFcn = createCallbackFcn(app, @movingaveragefilterButtonPushed, true);
            app.movingaveragefilterButton.Text = 'moving average filter';
            app.WindowSizeLabel = uilabel(app.UIFigure);
            app.WindowSizeLabel.Text = {'Window'; 'Size'};
            app.WindowSizeSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Recorder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end